classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MotorcodeButton                 matlab.ui.control.Button
        LinkcodeButton                  matlab.ui.control.Button
        DesiredENDZEditField            matlab.ui.control.NumericEditField
        DesiredENDZEditFieldLabel       matlab.ui.control.Label
        DesierdENDYEditField            matlab.ui.control.NumericEditField
        DesierdENDYEditFieldLabel       matlab.ui.control.Label
        DesierdENDXEditField            matlab.ui.control.NumericEditField
        DesierdENDXEditFieldLabel       matlab.ui.control.Label
        ImpedanceButton                 matlab.ui.control.Button
        ComplianceButton                matlab.ui.control.Button
        SimulationButton                matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        EnvironmentalstiffnessEditField  matlab.ui.control.NumericEditField
        EnvironmentalstiffnessEditFieldLabel  matlab.ui.control.Label
        DerivativeGainEditField         matlab.ui.control.NumericEditField
        DerivativeGainEditFieldLabel    matlab.ui.control.Label
        ProportionalGainEditField       matlab.ui.control.NumericEditField
        ProportionalGainEditFieldLabel  matlab.ui.control.Label
        MotorTableButton                matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        LinkTableButton                 matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        DHTableButton                   matlab.ui.control.Button
        DHTable                         matlab.ui.control.Table
        EnterDOFEditField               matlab.ui.control.NumericEditField
        EnterDOFEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
        ai                   % Cell array to store A values
        di                   % Cell array to store d values
        alphai              % Cell array to store alpha values
        thetai               % Cell array to store theta values
        Mass_of_Linki
        Inertia_of_Linki
        Mass_of_Motori
        Inertia_of_Motori
        Gear_Ratioi
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterDOFEditField
        function EnterDOFEditFieldValueChanged(app, event)
             % Get the value from the NumericEditField
            value = app.EnterDOFEditField.Value;
    
            % Validate the user input
            if isempty(value) || value <= 0 || mod(value, 1) ~= 0
            % Display an error message if the input is not a positive integer
                errordlg('Please enter a positive integer for the number of links.', 'Input Error');
                return;
            end
    
            % Generate the DH Table with appropriate dimensions
            dh_table_data = cell(value, 4);

            % Update the table data
            app.DHTable.Data = dh_table_data;     

            % Store the DH parameter values in variables
            app.ai = cell(value, 1);
            app.alphai = cell(value, 1);
            app.di = cell(value, 1);
            app.thetai=cell(value,1);


        end

        % Cell edit callback: DHTable
        function DHTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            

             % This function is executed when a cell in the DH table is edited

            % Get the indices of the edited cell
            row = event.Indices(1);
            col = event.Indices(2);

            % Get the new value entered by the user
            new_value = event.NewData;

            % Update the corresponding variable based on the column
            switch col
                case 1
                    app.ai{row} = new_value;
                  
                case 2
                    app.alphai{row} = new_value;
                    
                case 3
                    app.di{row} = new_value;
                    
                case 4
                    app.thetai{row} = new_value;
                
            end
        end

        % Button pushed function: DHTableButton
        function DHTableButtonPushed(app, event)
            % Request the number of prismatic joints from the user

            n = app.EnterDOFEditField.Value;


            % Initialize symbolic variables for DH parameters

            theta = sym('theta', [1 n], 'real');  % Joint displacement (prismatic)

            a = sym('a', [1 n]);                  % Link lengths

            alpha = sym('alpha', [1 n]);          % Link twists

            d = sym('d', [1 n]);                  % Link offsets


            % Get user input for DH parameters from the UI table

            dh_table_data = app.DHTable.Data;

            for i = 1:n
                a(i) = (dh_table_data{i, 1});
               
                alpha(i) = (dh_table_data{i, 2}); % Assuming alpha is in degrees
               
                d(i) = (dh_table_data{i, 3});
               
                theta(i) = (dh_table_data{i, 4}); % Assuming theta is in degrees
            end

        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)

            indices = event.Indices;
            newData = event.NewData;
            value_link = app.EnterDOFEditField.Value;
            

             % This function is executed when a cell in the DH table is edited

            % Get the indices of the edited cell
            row = event.Indices(1);
            col = event.Indices(2);

            % Get the new value entered by the user
            new_value = event.NewData;

            % Update the corresponding variable based on the column
            switch col
                case 1
                    app.Mass_of_Linki{row} = new_value;
                  
                case 2
                    app.Inertia_of_Linki{row} = new_value;
            end
        end

        % Button pushed function: LinkTableButton
        function LinkTableButtonPushed(app, event)
                % Get the value from the NumericEditField
            value_link = app.EnterDOFEditField.Value;
    
            % Validate the user input
            if isempty(value_link) || value_link <= 0 || mod(value_link, 1) ~= 0
            % Display an error message if the input is not a positive integer
                errordlg('Please enter a positive integer for the number of links.', 'Input Error');
                return;
            end
    
            % Generate the DH Table with appropriate dimensions
            UITable2_data = cell(value_link, 2);

            % Update the table data
            app.UITable2.Data = UITable2_data;     

            % Store the DH parameter values in variables
            app.Mass_of_Linki= cell(value_link, 1);
            app.Inertia_of_Linki = cell(value_link, 1);


        end

        % Button pushed function: MotorTableButton
        function MotorTableButtonPushed(app, event)
             % Get the value from the NumericEditField
            value_motor = app.EnterDOFEditField.Value;
    
            % Validate the user input
            if isempty(value_motor) || value_motor <= 0 || mod(value_motor, 1) ~= 0
            % Display an error message if the input is not a positive integer
                errordlg('Please enter a positive integer for the number of links.', 'Input Error');
                return;
            end
    
            % Generate the DH Table with appropriate dimensions
            UITable3_data = cell(value_motor, 3);

            % Update the table data
            app.UITable3.Data = UITable3_data;     

            % Store the DH parameter values in variables
            app.Mass_of_Motori= cell(value_motor, 1);
            app.Inertia_of_Motori = cell(value_motor, 1);
            app.Gear_Ratioi = cell(value_motor, 1);
        end

        % Button pushed function: MotorcodeButton
        function MotorcodeButtonPushed(app, event)
            value_motor = app.EnterDOFEditField.Value;
            mass_motor = zeros(1, n);
            inertia_motor = cell(1, n); % Initialize cell array for inertia matrix for links
            Gear_ratio = zeros(1,n);
              n=value_motor ;
            for i = 1:n
                mass_motor(i) = app.UITable3.Data{i, 1}; % Assuming mass is already numeric
                inertia_motor{i} = app.UITable3.Data{i,2}; % Convert string to numeric array (assuming inertia is represented as a vector)
                Gear_ratio(i) = app.UITable3.Data{i,3};
            end
        end

        % Button pushed function: LinkcodeButton
        function LinkcodeButtonPushed(app, event)
           value_link = app.EnterDOFEditField.Value;

           n = value_link;


           mass_link = zeros(1, n);

           inertia_link = cell(1, n); % Initialize cell array for inertia matrix for links


           for i = 1:n
    
               % Access the data directly from the UITable2
    
    
               mass_link(i) = app.UITable2.Data{i, 1}; % Assuming mass is already numeric
    
               inertia_input = app.UITable2.Data{i, 2}; % Retrieve inertia as a string
    
               inertia_link{i} = str2num(inertia_input); % Convert string to numeric array (assuming inertia is represented as a vector)

           end

        end

        % Cell edit callback: UITable3
        function UITable3CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            value_motor = app.EnterDOFEditField.Value;
            

             % This function is executed when a cell in the DH table is edited

            % Get the indices of the edited cell
            row = event.Indices(1);
            col = event.Indices(2);

            % Get the new value entered by the   
            new_value = event.NewData;

            % Update the corresponding variable based on the column
            switch col
                case 1
                    app.Mass_of_Motori{row} = new_value;
                  
                case 2
                    app.Inertia_of_Motori{row} = new_value;
                case 3
                    app.Gear_Ratioi{row} = new_value;
            end 
        end

        % Value changed function: ProportionalGainEditField
        function ProportionalGainEditFieldValueChanged(app, event)
            value = app.ProportionalGainEditField.Value;
            Proportinal_gain
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterDOFEditFieldLabel = uilabel(app.UIFigure);
            app.EnterDOFEditFieldLabel.Text = 'Enter DOF';
            app.EnterDOFEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterDOFEditField.ValueChangedFcn = createCallbackFcn(app, @EnterDOFEditFieldValueChanged, true);
            app.DHTable = uitable(app.UIFigure);
            app.DHTable.ColumnName = {'a'; 'Alpha'; 'd'; 'theta'};
            app.DHTable.RowName = {};
            app.DHTable.ColumnEditable = [true true true true];
            app.DHTable.CellEditCallback = createCallbackFcn(app, @DHTableCellEdit, true);
            app.DHTableButton = uibutton(app.UIFigure, 'push');
            app.DHTableButton.ButtonPushedFcn = createCallbackFcn(app, @DHTableButtonPushed, true);
            app.DHTableButton.Text = 'DH-Table';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Mass of Link'; 'Inertia of Link'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = [true true];
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.LinkTableButton = uibutton(app.UIFigure, 'push');
            app.LinkTableButton.ButtonPushedFcn = createCallbackFcn(app, @LinkTableButtonPushed, true);
            app.LinkTableButton.Text = 'Link-Table';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Mass of motor'; 'Inertia of motor'; 'Gear ratio'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = [true true true];
            app.UITable3.CellEditCallback = createCallbackFcn(app, @UITable3CellEdit, true);
            app.MotorTableButton = uibutton(app.UIFigure, 'push');
            app.MotorTableButton.ButtonPushedFcn = createCallbackFcn(app, @MotorTableButtonPushed, true);
            app.MotorTableButton.Text = 'Motor-Table';
            app.ProportionalGainEditFieldLabel = uilabel(app.UIFigure);
            app.ProportionalGainEditFieldLabel.Text = 'Proportional Gain';
            app.ProportionalGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProportionalGainEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionalGainEditFieldValueChanged, true);
            app.ProportionalGainEditField.Value = 10;
            app.DerivativeGainEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeGainEditFieldLabel.Text = 'Derivative Gain';
            app.DerivativeGainEditField = uieditfield(app.UIFigure, 'numeric');
            app.DerivativeGainEditField.Value = 10;
            app.EnvironmentalstiffnessEditFieldLabel = uilabel(app.UIFigure);
            app.EnvironmentalstiffnessEditFieldLabel.Text = 'Environmental stiffness';
            app.EnvironmentalstiffnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnvironmentalstiffnessEditField.Value = 100;
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.Text = 'Generate';
            app.SimulationButton = uibutton(app.UIFigure, 'push');
            app.SimulationButton.Text = 'Simulation';
            app.ComplianceButton = uibutton(app.UIFigure, 'push');
            app.ComplianceButton.Text = 'Compliance';
            app.ImpedanceButton = uibutton(app.UIFigure, 'push');
            app.ImpedanceButton.Text = 'Impedance';
            app.DesierdENDXEditFieldLabel = uilabel(app.UIFigure);
            app.DesierdENDXEditFieldLabel.Text = 'Desierd END X';
            app.DesierdENDXEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesierdENDXEditField.Value = 4;
            app.DesierdENDYEditFieldLabel = uilabel(app.UIFigure);
            app.DesierdENDYEditFieldLabel.Text = 'Desierd END Y';
            app.DesierdENDYEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesierdENDYEditField.Value = 5;
            app.DesiredENDZEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredENDZEditFieldLabel.Text = 'Desired  END Z';
            app.DesiredENDZEditField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredENDZEditField.Value = 4;
            app.LinkcodeButton = uibutton(app.UIFigure, 'push');
            app.LinkcodeButton.ButtonPushedFcn = createCallbackFcn(app, @LinkcodeButtonPushed, true);
            app.LinkcodeButton.Text = 'Link-code';
            app.MotorcodeButton = uibutton(app.UIFigure, 'push');
            app.MotorcodeButton.ButtonPushedFcn = createCallbackFcn(app, @MotorcodeButtonPushed, true);
            app.MotorcodeButton.Text = 'Motor-code';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end